classdef detect_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        StopButton           matlab.ui.control.Button
        THCendsSpinner       matlab.ui.control.Spinner
        THCendsSpinnerLabel  matlab.ui.control.Label
        ClearButton          matlab.ui.control.Button
        tmeshsSpinner        matlab.ui.control.Spinner
        tmeshsSpinnerLabel   matlab.ui.control.Label
        THCstartsSpinner     matlab.ui.control.Spinner
        PmrAmolLabel         matlab.ui.control.Label
        RunButton            matlab.ui.control.Button
        SaveButton           matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        trajectories % Description
        t = 86400;
        tim = 0;
        exeFlag = true;
        cnt = 1;
        thcstart = 21600;
        thcend = 43200;
        init = [30; 0; 0; 3500; 0; 100; 0; 0; 0; 0; 0; 0; 0; 0; 0];
        name_elements = ["RNAP", "PmrA~P2", "mRNAa", "PmrA", "mRNAb", "PmrB", "PmrB~P", "PmrB2", "PmrB~P2", "PmrA·PmrB~P2", "PmrA~P", "PmrA~P·PmrB2", "mRNArep", "reporter", "THC"];
        par = struct(c1 = 0.0751, ...
            k1 = 9.23e-3, ...
            k2 = 6.76e-3, ...
            k3 = 9.23e-3, ...
            c2 = 1e-4, ...
            c3 = 0.1, ...
            c4 = 0.004, ...
            c5 = 6.42e-5, ...
            c6 = 6.42e-5, ...
            c7 = 6.42e-5, ...
            c8 = 1e-4, ...
            c9 = 0.3, ...
            c10 = 0.004, ...
            c11 = 0.1, ...
            c12 = 0.5, ...
            c13 = 6.42e-5, ...
            c28 = 0.05, ...
            c29 = 150, ... % PmrB~P2 --> PmrB2 随外界刺激变化 两篇论文分别是0.51, 50 equation-free那篇将刺激强度定义为c28/c29
            c24 = 1e-4, ...
            c25 = 1.6, ...
            c26 = 1e-4, ...
            c27 = 1.6, ...
            c14 = 6.42e-5, ...
            c15 = 6.42e-5, ...
            c16 = 6.42e-5, ...
            c17 = 0.083, ...
            c18 = 0.5, ...
            c19 = 0.3, ...
            c20 = 0.01, ...
            c21 = 0.1, ...
            c22 = 0.0042, ...
            c23 = 6.202e-4, ...
            c30 = 0.04125, ...
            Ka = 6.2e7);
        prop = @(x,par)([par.c1*par.k2*x(1)*x(2)/(1+par.k2*x(1)*x(2)+par.k1*x(1)+par.k3*x(2)) ...
                            par.c2*par.k1*x(1)/(1+par.k2*x(1)*x(2)+par.k1*x(1)+par.k3*x(2)) ...
                            par.c3*x(3) ...
                            par.c4*x(3) ...
                            par.c5*x(4) ...
                            par.c6*x(11) ...
                            par.c7*x(11) ...
                            par.c8 ...
                            par.c9*x(5) ...
                            par.c10*x(5) ...
                            par.c13*x(6) ...
                            par.c11*x(6)*(x(6)-1) ...
                            par.c12*x(6) ...
                            par.c28*x(6) ...
                            par.c29*x(9) ...
                            par.c24*x(4)*x(9) ...
                            par.c25*x(10) ...
                            par.c26*x(11)*x(6) ...
                            par.c27*x(12) ...
                            par.c14*x(7) ...
                            par.c15*x(8) ...
                            par.c16*x(9) ...
                            par.c17*x(11)*(x(11)-1) ...
                            par.c18*x(2) ...
                            par.c19*par.k2*x(1)*x(2)/(1+par.k2*x(1)*x(2)+par.k1*x(1)+par.k3*x(2)) ...
                            par.c20*par.k1*x(1)/(1+par.k2*x(1)*x(2)+par.k1*x(1)+par.k3*x(2)) ...
                            par.c21*x(13) ...
                            par.c22*x(13) ...
                            par.c23*x(14) ...
                            ]);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Detect GUI';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            detect_stoch = cell2mat(struct2cell(load("detect-stoch.mat")));
            num_elements = size(app.init);
            tmesh = linspace(0, app.t, app.t);
            num_reactions = size(detect_stoch, 2);
            app.exeFlag = true;
            app.RunButton.Text = [num2str(app.tim), '/', num2str(app.t)];
            drawnow;

            %app.trajectories = SSA(tmesh, app.par, app.prop, detect_stoch, app.init, app.thcstart, app.thcend, app.exeFlag);

            app.tim = 0;                                           %current time
            state = app.init(:);                                 %variable with current system state
            app.trajectories = zeros(length(app.init), length(tmesh)); %preparing output trajectories
            app.trajectories(:, 1) = app.init(:);                      %setting initial value as the first element in trajectories
            cindx = 2;                                       %current trajectories index
            N = length(tmesh);                               %number of time points
            
            while app.tim < tmesh(end)
                Q = feval(app.prop, state, app.par);        %calculating propensities of the reactions
                %         for i=1:size(Q)
                %             if Q(i) < 0
                %                 Q(i) = +0.0; % since propensity functions include square items, when the system lacks something
                %                 % some propensity functions may be positive and bigger and bigger as the iteration goes on
                %             end
                %         end
                qq = Q(Q > 0);
                Qs = sum(Q);                        %total propensity
                dt = -log(rand())/Qs;               %generating time to the next reaction
                R = sum(rand >= cumsum([0, Q])/Qs); %selecting reaction
                state = state + detect_stoch(:, R);        %updating state
                if ~isempty(find(state < 0, 1))     %in case there are substrates of negative amount
                    state = state - detect_stoch(:, R);
                    [~, n] = find(Q == qq(randperm(numel(qq), 1)));
                    state = state + detect_stoch(:, n(randperm(numel(n), 1)));
                end
                app.tim = app.tim + dt;                         %updating time

                if(app.tim >= app.t)                %display current progress on the button
                    app.RunButton.Text = 'Finished.';
                    drawnow;
                    app.RunButton.Text = 'Run';
                    drawnow;
                else
                    app.RunButton.Text = [num2str(app.tim), '/', num2str(app.t)];
                    drawnow;
                end

                if app.exeFlag == false            %stop the execution
                    msgbox('User aborted executiom.', 'Stopped', 'warn');
                    return;
%                     error('User aborted execution.');
                end

                %writing the output
                while cindx <= N && app.tim > tmesh(cindx)
                    app.trajectories(:, cindx) = state;
                    cindx = cindx+1;
                end
                if app.tim >= app.thcstart && app.tim <= app.thcend %THC takein time interval
                    app.par.c29 = 0.5; %parameter modified
                end
                if app.tim > app.thcend
                    app.par.c29 = 150;
                end
            end

            plot(app.UIAxes, app.trajectories(14, :), 'b');
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.trajectories(13, :), 'r');
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.trajectories(9, :), 'g')
            legend(app.UIAxes, app.name_elements(14), app.name_elements(13), app.name_elements(9), 'location', 'NorthWest')
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fileFolder = 'Detect-Result';
            dirOutput = dir(fullfile(fileFolder));
            maxcnt = -1;
            for i=1:size(dirOutput, 1)
                match_num = regexp(dirOutput(i).name, '\((\d+)\)', 'tokens');
                if isempty(match_num)
                    num = 0;
                else
                    tmp = match_num{:};
                    num = str2num(tmp{:});
                end
                maxcnt = max(maxcnt, num);
            end

            tmp = app.trajectories;
            filename = "detect-24h("+num2str(maxcnt+1)+").mat";
            save(fullfile("Detect-Result", filename), 'tmp');
            app.cnt = maxcnt+1;

            msgbox("Saved as " + filename + " in the folder Detect-Result.", 'Success', 'help');
        end

        % Button down function: RightPanel
        function LoadStochMatrixButtonPushed(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

            plot(app.UIAxes, app.trajectories(14, :), 'b');
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.trajectories(13, :), 'r');
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.trajectories(9, :), 'g')
            legend(app.UIAxes, app.name_elements(14), app.name_elements(13), app.name_elements(9), 'location', 'NorthWest')
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
            legend(app.UIAxes, 'off');
        end

        % Callback function
        function C29SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.par.c29 = changingValue;
        end

        % Value changing function: THCstartsSpinner
        function THCstartsSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.thcstart = changingValue;
        end

        % Value changing function: THCendsSpinner
        function THCendsSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.thcend = changingValue;
        end

        % Callback function
        function THCmolLSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.init(15) = changingValue;
        end

        % Value changing function: tmeshsSpinner
        function tmeshsSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.t = changingValue;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.exeFlag = false;
            pause(0.1);
            app.RunButton.Text = 'Stopped';
            drawnow;
            pause(0.5);
            app.RunButton.Text = 'Run';
            drawnow;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'logo.jpg';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PmrAmolLabel = uilabel(app.LeftPanel);
            app.PmrAmolLabel.Text = 'THC start, s';
            app.THCstartsSpinner = uispinner(app.LeftPanel);
            app.THCstartsSpinner.ValueChangingFcn = createCallbackFcn(app, @THCstartsSpinnerValueChanging, true);
            app.THCstartsSpinner.Value = 21600;
            app.tmeshsSpinnerLabel = uilabel(app.LeftPanel);
            app.tmeshsSpinnerLabel.Text = 'tmesh, s';
            app.tmeshsSpinner = uispinner(app.LeftPanel);
            app.tmeshsSpinner.ValueChangingFcn = createCallbackFcn(app, @tmeshsSpinnerValueChanging, true);
            app.tmeshsSpinner.Value = 86400;
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.THCendsSpinnerLabel = uilabel(app.LeftPanel);
            app.THCendsSpinnerLabel.Text = 'THC end, s';
            app.THCendsSpinner = uispinner(app.LeftPanel);
            app.THCendsSpinner.ValueChangingFcn = createCallbackFcn(app, @THCendsSpinnerValueChanging, true);
            app.THCendsSpinner.Value = 43200;
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @LoadStochMatrixButtonPushed, true);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'THC Degradation Process')
            xlabel(app.UIAxes, 'Time, s')
            ylabel(app.UIAxes, 'Substance, M')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 86400];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detect_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end